classdef MRIGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        TrajectoryLabel               matlab.ui.control.Label
        MRIImagePanel                 matlab.ui.container.Panel
        MRIAxes                       matlab.ui.control.UIAxes
        RawImagePanel                 matlab.ui.container.Panel
        RawPhantomAxes                matlab.ui.control.UIAxes
        SimulatorOptionsPanel         matlab.ui.container.Panel
        MaskLabel                     matlab.ui.control.Label
        MaskSlider                    matlab.ui.control.Slider
        MaskSliderLabel               matlab.ui.control.Label
        PointsLabel                   matlab.ui.control.Label
        LinesLabel                    matlab.ui.control.Label
        SlicesMenu                    matlab.ui.control.DropDown
        PointsMenu                    matlab.ui.control.DropDown
        MaskMenu                      matlab.ui.control.DropDown
        PhantomMenu                   matlab.ui.control.DropDown
        RunSimulatorButton            matlab.ui.control.Button
        TrajectoryMenu                matlab.ui.control.DropDown
        MaskAxes                      matlab.ui.control.UIAxes
        MRIAcquisitionSimulatorLabel  matlab.ui.control.Label
        TrajectoryAxes                matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function clearAxes(~, handles)
                axes(handles.RawPhantomAxes); cla;
                axes(handles.MRIAxes); cla;
        end
        
        function linesText_Callback(~, hObject, ~, handles)

            value = get(hObject, 'String');

            if isempty(str2double(value))
                set(hObject, 'String', handles.trajInfo.num_lines);
            else
                handles.trajInfo.num_lines = str2double(value);
                guidata(hObject, handles);
            end
        end
        
        function linesText_CreateFcn(~, hObject, ~, ~)
            if ismac && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','gray');
            end
        end
        
        function pointsText_Callback(~, hObject, ~, handles)
            val = get(hObject, 'String');
            if isempty(str2double(val))
                set(hObject, 'String', handles.trajInfo.num_points_per_line);
            else
                handles.trajInfo.num_points_per_line = str2double(val);
                guidata(hObject, handles);
            end
        end
        
        function pointsText_CreateFcn(~, hObject, ~, ~)
            if ismac && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','gray');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MRIGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);
            
            handles.output = hObject;
            guidata(hObject, handles);
            set(handles.SlicesMenu, 'Value', 3);
            set(handles.PointsMenu, 'Value', 3);
            guidata(hObject, handles);
        end

        % Value changed function: PhantomMenu
        function PhantomMenuValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            opt = get(handles.PhantomMenu, 'Value');
            axes(handles.RawPhantomAxes);
            switch opt
                case 1
                    clearAxes(app, handles)
                case 2
                    imshow('C:\Users\hoang\Desktop\medical-imaging-project-main\medical-imaging-project-main\images\phantom_type1.png');
                case 3
                    imshow('C:\Users\hoang\Desktop\medical-imaging-project-main\medical-imaging-project-main\images\phantom_type2.png');
                case 4
                    imshow('.C:\Users\hoang\Desktop\medical-imaging-project-main\medical-imaging-project-main\images\phantom_type3.png');
                case 5
                    [file, path, ~] = uigetfile({'*.png;*.jpg;*.jpeg;*.JPG;*.JPEG'});
                    if ischar(file) && ischar(path)
                        imdata = imread(strcat(path, file));
                        if size(imdata, 3) == 3
                            imdata = rgb2gray(imdata);
                        end
                        imdata = imresize(imdata, [3840,3840]);
                        imshow(imdata);
                    else
                        clearAxes(app, handles)
                    end
            end
        end

        % Value changed function: TrajectoryMenu
        function TrajectoryMenuValueChanged(app, event)

          % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            opt = get(handles.TrajectoryMenu, 'Value');
            axes(handles.TrajectoryAxes);
            switch opt
                case 1
                    cla;
                case 2
                    imshow('C:\Users\hoang\Desktop\medical-imaging-project-main\medical-imaging-project-main\images\cartesian.png');
                case 3
                    imshow('C:\Users\hoang\Desktop\medical-imaging-project-main\medical-imaging-project-main\images\radial.png');
            end
        end

        % Button pushed function: RunSimulatorButton
        function RunSimulatorButtonPushed(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            trajectory = get(handles.TrajectoryMenu, 'Value');
            
            if(isempty(get(handles.RawPhantomAxes, 'Children')))
                return;
            end
            
            lines = 2^(get(handles.SlicesMenu, 'Value') + 3);
            points = 2^(get(handles.PointsMenu, 'Value') + 3);
            maskPercent = get(handles.MaskSlider, 'Value');
            maskType = get(handles.MaskMenu, 'Value');
            
            
            switch trajectory
                case 1
                    return;
                case 2

                    [MRI, mask] = MRI_Cartesian(getimage(handles.RawPhantomAxes), lines, points, maskType - 2, maskPercent);
                    axes(handles.MaskAxes);
                    imshow(mask);

                case 3

                    [MRI, mask] = MRI_Radial(getimage(handles.RawPhantomAxes), lines, points, maskType - 2, maskPercent);
                    
                    axes(handles.MaskAxes);
                    imshow(mask);
            
            end
        
            axes(handles.MRIAxes);
            imshow(MRI, [0, max(MRI(:))]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MRIGUI';
            app.figure1.Tag = 'figure1';
            app.TrajectoryAxes = uiaxes(app.figure1);
            app.TrajectoryAxes.Tag = 'TrajectoryAxes';
            app.MRIAcquisitionSimulatorLabel = uilabel(app.figure1);
            app.MRIAcquisitionSimulatorLabel.Text = 'MRI Acquisition Simulator';
            app.SimulatorOptionsPanel = uipanel(app.figure1);
            app.SimulatorOptionsPanel.Title = 'Simulator Options';
            app.MaskAxes = uiaxes(app.SimulatorOptionsPanel);
            app.MaskAxes.Tag = 'MaskAxes';
            app.TrajectoryMenu = uidropdown(app.SimulatorOptionsPanel);
            app.TrajectoryMenu.Items = {'Trajectory', 'Cartesian', 'Radial', ''};
            app.TrajectoryMenu.ValueChangedFcn = createCallbackFcn(app, @TrajectoryMenuValueChanged, true);
            app.TrajectoryMenu.Tag = 'TrajectoryMenu';
            app.TrajectoryMenu.Value = 'Trajectory';
            app.RunSimulatorButton = uibutton(app.SimulatorOptionsPanel, 'push');
            app.RunSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulatorButtonPushed, true);
            app.RunSimulatorButton.Tag = 'RunSimulatorButton';
            app.RunSimulatorButton.Text = 'Run Simulator';
            app.PhantomMenu = uidropdown(app.SimulatorOptionsPanel);
            app.PhantomMenu.Items = {'Phantom', 'Phantom 1', 'Phantom 2', 'Phantom 3'};
            app.PhantomMenu.ValueChangedFcn = createCallbackFcn(app, @PhantomMenuValueChanged, true);
            app.PhantomMenu.Tag = 'PhantomMenu';
            app.PhantomMenu.Value = 'Phantom';
            app.MaskMenu = uidropdown(app.SimulatorOptionsPanel);
            app.MaskMenu.Items = {'Mask', 'square', 'circle', 'none'};
            app.MaskMenu.Tag = 'MaskMenu';
            app.MaskMenu.Value = 'Mask';
            app.PointsMenu = uidropdown(app.SimulatorOptionsPanel);
            app.PointsMenu.Items = {'16', '32', '64', '128'};
            app.PointsMenu.Tag = 'PointsMenu';
            app.PointsMenu.Value = '16';
            app.SlicesMenu = uidropdown(app.SimulatorOptionsPanel);
            app.SlicesMenu.Items = {'16', '32', '64', '128'};
            app.SlicesMenu.Tag = 'SlicesMenu';
            app.SlicesMenu.Value = '16';
            app.LinesLabel = uilabel(app.SimulatorOptionsPanel);
            app.LinesLabel.Text = 'Lines';
            app.PointsLabel = uilabel(app.SimulatorOptionsPanel);
            app.PointsLabel.Text = 'Points';
            app.MaskSliderLabel = uilabel(app.SimulatorOptionsPanel);
            app.MaskSliderLabel.Text = '% Mask';
            app.MaskSlider = uislider(app.SimulatorOptionsPanel);
            app.MaskSlider.Limits = [0.1 1];
            app.MaskSlider.MajorTicks = [];
            app.MaskSlider.MinorTicks = [];
            app.MaskSlider.Tag = 'MaskSlider';
            app.MaskSlider.Value = 1;
            app.MaskLabel = uilabel(app.SimulatorOptionsPanel);
            app.MaskLabel.Text = 'Mask';
            app.RawImagePanel = uipanel(app.figure1);
            app.RawImagePanel.Title = 'Raw Image';
            app.RawPhantomAxes = uiaxes(app.RawImagePanel);
            app.RawPhantomAxes.Tag = 'RawPhantomAxes';
            app.MRIImagePanel = uipanel(app.figure1);
            app.MRIImagePanel.Title = 'MRI Image';
            app.MRIAxes = uiaxes(app.MRIImagePanel);
            app.MRIAxes.Tag = 'MRIAxes';
            app.TrajectoryLabel = uilabel(app.figure1);
            app.TrajectoryLabel.Text = 'Trajectory';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRIGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MRIGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end